classdef SessionViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        BaseGridLayout         matlab.ui.container.GridLayout
        GridLayout2            matlab.ui.container.GridLayout
        ResponseButtonGroup    matlab.ui.container.ButtonGroup
        SpikeRasterButton      matlab.ui.control.RadioButton
        NoneButton             matlab.ui.control.RadioButton
        GridLayout             matlab.ui.container.GridLayout
        GridLayout3            matlab.ui.container.GridLayout
        ParadigmDropDown       matlab.ui.control.DropDown
        ParadigmDropDownLabel  matlab.ui.control.Label
        SessionEditField       matlab.ui.control.EditField
        SessionEditFieldLabel  matlab.ui.control.Label
        PlotGridLayout         matlab.ui.container.GridLayout
        TimeGridLayout         matlab.ui.container.GridLayout
        SkipForwardButton      matlab.ui.control.Button
        PlayButton             matlab.ui.control.Button
        PauseButton            matlab.ui.control.Button
        SkipBackButton         matlab.ui.control.Button
        TimeSlider             matlab.ui.control.Slider
        TimeEditField          matlab.ui.control.NumericEditField
        RespUIAxes             matlab.ui.control.UIAxes
        StimUIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        %% Data
        Fn string
        Session spiky.ephys.Session
        Info spiky.ephys.SessionInfo
        Minos spiky.minos.MinosInfo
        Spikes spiky.core.Spikes
        Video spiky.minos.ScreenCapture
        HasVideo logical

        %% Player
        IsPlaying logical = false
        Timer timer
        CurrentTime double

        %% Handles
        HInteractable matlab.graphics.Graphics
        HFrame matlab.graphics.primitive.Image

        %% Drawers
        RespDrawer spiky.app.Drawer
        StimDrawers spiky.app.Drawer
    end
    
    methods (Access = public)

        function switchStatus(app, enabled)
            if enabled
                status = "on";
            else
                status = "off";
            end
            for ii = 1:numel(app.HInteractable)
                app.HInteractable.Enable = status;
            end
        end
        
        function loadSession(app, fn, ses, info, minos, spikes)
            arguments
                app spiky.app.SessionViewer
                fn string
                ses spiky.ephys.Session = spiky.ephys.Session.empty
                info spiky.ephys.SessionInfo = spiky.ephys.SessionInfo.empty
                minos spiky.minos.MinosInfo = spiky.minos.MinosInfo.empty
                spikes spiky.core.Spikes = spiky.core.Spikes.empty
            end
            app.Fn = fn;
            if isempty(ses)
                app.Session = spiky.ephys.Session(fn);
            else
                app.Session = ses;
            end
            if isempty(info)
                app.Info = app.Session.getInfo();
            else
                app.Info = info;
            end
            if isempty(minos)
                app.Minos = app.Session.getMinos();
            else
                app.Minos = minos;
            end
            if isempty(spikes)
                spikes = ses.getSpikes();
                app.Spikes = spikes.Spikes;
            else
                app.Spikes = spikes;
            end
            app.SessionEditField.Value = fn;
            app.TimeSlider.Limits = [0 app.Info.Duration];
            app.TimeEditField.Limits = [0 app.Info.Duration];
            app.Video = app.Minos.getScreenCapture();
            app.HasVideo = ~isempty(app.Video.Path) && exist(app.Video.Path, "file");
            app.loadVideo();
        end
        
        function loadVideo(app)
            if ~app.HasVideo
                return
            end
            app.Video = app.Video.open();
            frame = app.Video.getFrame(0);
            app.HFrame = image(app.StimUIAxes, [0 1], [1 0], frame);
            uistack(app.HFrame, "bottom");
            app.StimUIAxes.YDir = "normal";
            app.StimUIAxes.XLim = [0 1];
            app.StimUIAxes.YLim = [0 1];
            app.setTime(0);
            app.Timer = timer(Period=1/app.Video.FrameRate, ExecutionMode="fixedRate", ...
                BusyMode="drop", TimerFcn=@(~, ~) app.updateTime(NaN));
        end

        function updateTime(app, t, updateFrame)
            arguments
                app spiky.app.SessionViewer
                t double = NaN
                updateFrame logical = true
            end
            if ~isgraphics(app.HFrame)
                return
            end
            if updateFrame
                app.HFrame.CData = app.Video.getFrame(t);
            else
                app.Video.CurrentTime = t;
            end
            if isnan(t)
                t = app.Video.CurrentTime;
            else
                app.applyToDrawer(@(x) x.jump(t));
            end
            app.setTime(t);
        end

        function setTime(app, t)
            arguments
                app spiky.app.SessionViewer
                t double = NaN
            end
            app.CurrentTime = t;
            app.TimeEditField.Value = round(t, 2);
            app.TimeSlider.Value = t;
        end

        function play(app)
            if app.IsPlaying
                return
            end
            app.IsPlaying = true;
            app.PlayButton.Visible = false;
            app.PauseButton.Visible = true;
            app.Timer.start();
            app.applyToDrawer(@(x) x.start(app.CurrentTime))
        end

        function pause(app)
            if ~app.IsPlaying
                return
            end
            app.IsPlaying = false;
            app.Timer.stop();
            app.PlayButton.Visible = true;
            app.PauseButton.Visible = false;
            app.applyToDrawer(@(x) x.pause(app.CurrentTime))
        end

        function applyToDrawer(app, func)
            for ii = 1:numel(app.StimDrawers)
                func(app.StimDrawers(ii))
            end
            for ii = 1:numel(app.RespDrawer)
                func(app.RespDrawer(ii))
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, fn, ses, info, minos, spikes)
            arguments
                app spiky.app.SessionViewer
                fn string = string.empty
                ses spiky.ephys.Session = spiky.ephys.Session.empty
                info spiky.ephys.SessionInfo = spiky.ephys.SessionInfo.empty
                minos spiky.minos.MinosInfo = spiky.minos.MinosInfo.empty
                spikes spiky.core.Spikes = spiky.core.Spikes.empty
            end
            app.HInteractable = findall(app.UIFigure, "-property", "Enable");
            if isempty(ses)
                return
            end
            if isempty(fn)
                fn = ses.Name;
            end
            app.StimDrawers = [spiky.app.GazeDrawer(app, 0.05)
                spiky.app.FixationTargetDrawer(app, 0.05)];
            app.loadSession(fn, ses, info, minos, spikes);
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanged(app, event)
            value = event.Value;
            app.updateTime(value, ~app.IsPlaying);
        end

        % Value changed function: TimeEditField
        function TimeEditFieldValueChanged(app, event)
            value = event.Value;
            app.updateTime(value, ~app.IsPlaying);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.play();
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.pause();
        end

        % Selection changed function: ResponseButtonGroup
        function ResponseButtonGroupSelectionChanged(app, event)
            selectedButton = app.ResponseButtonGroup.SelectedObject;
            switch selectedButton.Text
                case "None"
                    delete(app.RespDrawer)
                case "Spike Raster"
                    app.RespDrawer = spiky.app.SpikeRasterDrawer(app, 0.1);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BaseGridLayout = uigridlayout(app.UIFigure);
            app.PlotGridLayout = uigridlayout(app.BaseGridLayout);
            app.StimUIAxes = uiaxes(app.PlotGridLayout);
            app.StimUIAxes.XAxisLocation = 'origin';
            app.StimUIAxes.XTick = [];
            app.StimUIAxes.YAxisLocation = 'origin';
            app.StimUIAxes.YTick = [];
            app.RespUIAxes = uiaxes(app.PlotGridLayout);
            app.RespUIAxes.XLim = [-1.77777777777778 1.77777777777778];
            app.RespUIAxes.YLim = [-1 1];
            app.RespUIAxes.YDir = 'reverse';
            app.RespUIAxes.XAxisLocation = 'origin';
            app.RespUIAxes.XTick = [];
            app.RespUIAxes.YAxisLocation = 'origin';
            app.RespUIAxes.YTick = [];
            app.TimeGridLayout = uigridlayout(app.PlotGridLayout);
            app.TimeEditField = uieditfield(app.TimeGridLayout, 'numeric');
            app.TimeEditField.ValueDisplayFormat = '%05.2f';
            app.TimeEditField.ValueChangedFcn = createCallbackFcn(app, @TimeEditFieldValueChanged, true);
            app.TimeSlider = uislider(app.TimeGridLayout);
            app.TimeSlider.Limits = [0 1];
            app.TimeSlider.MajorTicks = [];
            app.TimeSlider.MajorTickLabels = {};
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.TimeSlider.MinorTicks = [0 0.005 0.01 0.015 0.02 0.025 0.03 0.035 0.04 0.045 0.05 0.055 0.06 0.065 0.07 0.075 0.08 0.085 0.09 0.095 0.1 0.105 0.11 0.115 0.12 0.125 0.13 0.135 0.14 0.145];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
            app.SkipBackButton = uibutton(app.TimeGridLayout, 'push');
            app.SkipBackButton.Icon = fullfile(pathToMLAPP, 'IconsWhite', 'skip-back.svg');
            app.SkipBackButton.Text = '';
            app.PauseButton = uibutton(app.TimeGridLayout, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Icon = fullfile(pathToMLAPP, 'IconsWhite', 'pause.svg');
            app.PauseButton.Visible = 'off';
            app.PauseButton.Text = '';
            app.PlayButton = uibutton(app.TimeGridLayout, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = fullfile(pathToMLAPP, 'IconsWhite', 'play.svg');
            app.PlayButton.Text = '';
            app.SkipForwardButton = uibutton(app.TimeGridLayout, 'push');
            app.SkipForwardButton.Icon = fullfile(pathToMLAPP, 'IconsWhite', 'skip-forward.svg');
            app.SkipForwardButton.Text = '';
            app.GridLayout = uigridlayout(app.BaseGridLayout);
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.SessionEditFieldLabel = uilabel(app.GridLayout3);
            app.SessionEditFieldLabel.Text = 'Session';
            app.SessionEditField = uieditfield(app.GridLayout3, 'text');
            app.SessionEditField.Editable = 'off';
            app.ParadigmDropDownLabel = uilabel(app.GridLayout3);
            app.ParadigmDropDownLabel.Text = 'Paradigm';
            app.ParadigmDropDown = uidropdown(app.GridLayout3);
            app.ParadigmDropDown.Items = {'None'};
            app.ParadigmDropDown.Value = 'None';
            app.GridLayout2 = uigridlayout(app.BaseGridLayout);
            app.ResponseButtonGroup = uibuttongroup(app.GridLayout2);
            app.ResponseButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ResponseButtonGroupSelectionChanged, true);
            app.ResponseButtonGroup.Title = 'Response';
            app.NoneButton = uiradiobutton(app.ResponseButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.SpikeRasterButton = uiradiobutton(app.ResponseButtonGroup);
            app.SpikeRasterButton.Text = 'Spike Raster';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SessionViewer(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end